classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        GrossRentMultiplierforBuildingCEditField  matlab.ui.control.NumericEditField
        GrossRentMultiplierforBuildingCEditFieldLabel  matlab.ui.control.Label
        GrossRentMultiplierforBuildingBEditField  matlab.ui.control.NumericEditField
        GrossRentMultiplierforBuildingBEditFieldLabel  matlab.ui.control.Label
        GrossRentMultiplierforBuildingAEditField  matlab.ui.control.NumericEditField
        GrossRentMultiplierforBuildingAEditFieldLabel  matlab.ui.control.Label
        TotalDCFDevEditField         matlab.ui.control.NumericEditField
        TotalDCFDevEditFieldLabel    matlab.ui.control.Label
        TotalDCF2EditField           matlab.ui.control.NumericEditField
        TotalDCF2EditFieldLabel      matlab.ui.control.Label
        GrowthRateEditField          matlab.ui.control.NumericEditField
        GrowthRateEditFieldLabel     matlab.ui.control.Label
        NumberofYearsEditField       matlab.ui.control.NumericEditField
        NumberofYearsEditFieldLabel  matlab.ui.control.Label
        DiscountRateEditField        matlab.ui.control.NumericEditField
        DiscountRateEditFieldLabel   matlab.ui.control.Label
        TotalDCFEditField            matlab.ui.control.NumericEditField
        TotalDCFEditFieldLabel       matlab.ui.control.Label
        RuntheScriptButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RuntheScriptButton
        function RuntheScriptButtonPushed(app, event)
            % Getting value from field 1
            r = app.DiscountRateEditField.Value;
            % Getting value from field 2
            Y = app.NumberofYearsEditField.Value;
            % Getting value from field 3
            GR = app.GrowthRateEditField.Value;
            GRA = app.GrossRentMultiplierforBuildingAEditField.Value;
            GRB = app.GrossRentMultiplierforBuildingBEditField.Value;
            GRC = app.GrossRentMultiplierforBuildingAEditField.Value;
            Project_Definitive_app
            app.TotalDCFEditField.Value = Total_Discounted_Cash_Flow;
            app.TotalDCF2EditField.Value = Total_Discounted_Cash_Flow_2;
            if nrotd>0 | indx==1
                app.TotalDCFDevEditField.Value = Total_Discounted_Cash_Flow_3;
            else
                app.TotalDCFDevEditField.Value = Total_Discounted_Cash_Flow
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.RuntheScriptButton = uibutton(app.Panel, 'push');
            app.RuntheScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RuntheScriptButtonPushed, true);
            app.RuntheScriptButton.Text = 'Run the Script';
            app.TotalDCFEditFieldLabel = uilabel(app.Panel);
            app.TotalDCFEditFieldLabel.Text = 'Total DCF';
            app.TotalDCFEditField = uieditfield(app.Panel, 'numeric');
            app.DiscountRateEditFieldLabel = uilabel(app.Panel);
            app.DiscountRateEditFieldLabel.Text = 'Discount Rate';
            app.DiscountRateEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofYearsEditFieldLabel = uilabel(app.Panel);
            app.NumberofYearsEditFieldLabel.Text = 'Number of Years';
            app.NumberofYearsEditField = uieditfield(app.Panel, 'numeric');
            app.GrowthRateEditFieldLabel = uilabel(app.Panel);
            app.GrowthRateEditFieldLabel.Text = 'Growth Rate';
            app.GrowthRateEditField = uieditfield(app.Panel, 'numeric');
            app.TotalDCF2EditFieldLabel = uilabel(app.Panel);
            app.TotalDCF2EditFieldLabel.Text = 'Total DCF 2';
            app.TotalDCF2EditField = uieditfield(app.Panel, 'numeric');
            app.TotalDCFDevEditFieldLabel = uilabel(app.Panel);
            app.TotalDCFDevEditFieldLabel.Text = 'Total DCF Dev';
            app.TotalDCFDevEditField = uieditfield(app.Panel, 'numeric');
            app.GrossRentMultiplierforBuildingAEditFieldLabel = uilabel(app.Panel);
            app.GrossRentMultiplierforBuildingAEditFieldLabel.Text = 'Gross Rent Multiplier for Building A';
            app.GrossRentMultiplierforBuildingAEditField = uieditfield(app.Panel, 'numeric');
            app.GrossRentMultiplierforBuildingBEditFieldLabel = uilabel(app.Panel);
            app.GrossRentMultiplierforBuildingBEditFieldLabel.Text = 'Gross Rent Multiplier for Building B';
            app.GrossRentMultiplierforBuildingBEditField = uieditfield(app.Panel, 'numeric');
            app.GrossRentMultiplierforBuildingCEditFieldLabel = uilabel(app.Panel);
            app.GrossRentMultiplierforBuildingCEditFieldLabel.Text = 'Gross Rent Multiplier for Building C';
            app.GrossRentMultiplierforBuildingCEditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end